classdef LPRAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LicensePlateRecognitionStateIdentificaitonSystemsLabel  matlab.ui.control.Label
        DetectionPanel              matlab.ui.container.Panel
        PlateTypeLabel              matlab.ui.control.Label
        SeeDetailsButton            matlab.ui.control.Button
        StatePanel                  matlab.ui.container.Panel
        StateNameLabel              matlab.ui.control.Label
        StateImageAxes              matlab.ui.control.UIAxes
        CroppedPlateAxes            matlab.ui.control.UIAxes
        MainPanel                   matlab.ui.container.Panel
        SelectVehicleImageDropDown  matlab.ui.control.DropDown
        ImageDisplayAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ProcessingStepsImages % Description
    end
    
    methods (Access = private)

        function startDetection(app)
            isSmallPlate = false;
            % Get selected image from dropdown
            selectedImage = app.SelectVehicleImageDropDown.Value;

            parts = split(selectedImage, "/");
            if any(strcmp(parts, 'Motor')) || any(strcmp(parts, 'Lorry'))
                isSmallPlate = true;
            end
            
            % Load and display the selected image
            img = imread(selectedImage);
            imshow(img, 'Parent', app.ImageDisplayAxes);
            
            % Run backend processing using main.m
            [croppedPlate, plateType, stepsImages, plateText] = main(img, isSmallPlate);
            app.ProcessingStepsImages = stepsImages;
            %app.RecognizedPlateText = plateText;
            disp(plateText);

            % Display cropped plate
            imshow(croppedPlate, 'Parent', app.CroppedPlateAxes);
            
            % Update state display
            stateMap = containers.Map(...
                {'J', 'K', 'D', 'M', 'N', 'C', 'P', 'A', 'R', 'S', 'Q', 'B', 'T', 'V','H'}, ...
                {'Johor', 'Kedah', 'Kelantan', 'Malacca', 'Negeri Sembilan', 'Pahang', ...
                 'Penang', 'Perak', 'Perlis', 'Sabah', 'Sarawak', 'Selangor', ...
                 'Terengganu', 'Kuala Lumpur','Taxi'});
            if ~isempty(plateText) && isKey(stateMap, upper(plateText(1)))
                state = stateMap(upper(plateText(1)));
            else
                state = 'Unknown';
            end
            app.StateNameLabel.Text = sprintf('State: %s', state);
            
            if strcmp(state, 'Taxi')
                cla(app.StateImageAxes);

            else
                % Load and display state flag
                flagPath = fullfile('flags', [state '.jpg']);
    
                try
                    flagImg = imread(flagPath);
                    imshow(flagImg, 'Parent', app.StateImageAxes);
                catch
                    cla(app.StateImageAxes);
                    app.StateNameLabel.Text = sprintf('State: %s (Flag not found)', state);
                end
            end
     
    
                app.PlateTypeLabel.Text = sprintf('Plate Type: %s', plateType);
            
        end
        
        function [croppedPlate, plateType, stepsImages, plateText] = detectionProcess(~, img)
            % Call the main.m function for license plate detection
            [croppedPlate, plateType, stepsImages, plateText] = main(img);
            disp(plateText);
            % Extract plate text from the last valid candidate in main.m
            % Re-run plateDetect5 on the cropped plate to get the text
            %if ~isequal(croppedPlate, zeros(50, 50, 3, 'uint8'))
                %addpath('functions');
                %[plateText, ~] = plateDetect5(croppedPlate);
            %else
                %plateText = '';
            %end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            %app.ImageDisplayAxes.Visible = "off";
            %app.ImageDisplayAxes.Colormap = gray(256);
            %axis(app.ImageDisplayAxes,"image");

            %app.CroppedPlateAxes.Visible = "off";
            %app.CroppedPlateAxes.Colormap = gray(256);
            %axis(app.CroppedPlateAxes,"image");
            
            % Update the image and histograms
            %updateImage(app,"peppers.png");
            app.SelectVehicleImageDropDownValueChanged(struct('Source', app.SelectVehicleImageDropDown));
        end

        % Button pushed function: SeeDetailsButton
        function SeeDetailsButtonPushed(app, event)
            % Create a new figure to display image processing steps
            detailsFig = uifigure('Name', 'Image Processing Steps', ...
                'Position', [100, 100, 1000, 600], ...
                'Color', [0.95 0.95 0.95]);
        
            % Create a scrollable panel
            scrollPanel = uipanel(detailsFig, ...
                'Scrollable', 'on', ...
                'BackgroundColor', [0.95 0.95 0.95], ...
                'Position', [10, 10, 980, 580]);
        
            % Calculate the required height for all steps (130px per step + title)
            numSteps = size(app.ProcessingStepsImages, 1);
            contentHeight = 30 + numSteps * 130; % 30 for title, 130 per step
        
            % Title inside the scrollable panel
            uilabel(scrollPanel, ...
                'Position', [20, contentHeight - 30, 960, 30], ...
                'Text', 'Image Processing Steps for License Plate Recognition & State Identification System', ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'center', ...
                'BackgroundColor', [0.2 0.4 0.6], ...
                'FontColor', [1 1 1]);
        
            % Display each step with image and description
            for i = 1:numSteps
                % Create axes for image
                stepAxes = uiaxes(scrollPanel, ...
                    'Position', [20, contentHeight - 130 * i - 30, 250, 180], ...
                    'XTick', [], 'YTick', [], ...
                    'BackgroundColor', [1 1 1]);
                imshow(app.ProcessingStepsImages{i, 1}, 'Parent', stepAxes);
                title(stepAxes, sprintf('Step %d', i), 'FontSize', 12);
        
                % Create label for description
                uilabel(scrollPanel, ...
                    'Position', [240, contentHeight - 130 * i - 30, 740, 100], ...
                    'Text', app.ProcessingStepsImages{i, 2}, ...
                    'FontSize', 12, ...
                    'VerticalAlignment', 'top', ...
                    'WordWrap', 'on');
            end
        end

        % Value changed function: SelectVehicleImageDropDown
        function SelectVehicleImageDropDownValueChanged(app, event)
            startDetection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'LPR & SIS';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'Vehicle Image Input';
            app.ImageDisplayAxes = uiaxes(app.MainPanel);
            title(app.ImageDisplayAxes, 'Original Image')
            app.ImageDisplayAxes.XTick = [];
            app.ImageDisplayAxes.XTickLabel = '';
            app.ImageDisplayAxes.YTick = [];
            app.SelectVehicleImageDropDown = uidropdown(app.MainPanel);
            app.SelectVehicleImageDropDown.Items = {'Lorry\Lorry_33.jpg', 'Lorry\Lorry_34.jpg', 'Lorry\Lorry_36.jpg', 'Lorry\Lorry_37.jpg', 'Lorry\Lorry_38.jpg', 'Lorry\Lorry_39.jpg', 'Lorry\Lorry_40.jpg', 'Lorry\Lorry_41.jpg', 'Lorry\Lorry_42.jpg', 'Lorry\Lorry_43.jpg', '', 'Lorry\Lorry_1.jpg', 'Lorry\Lorry_3.jpg', 'Lorry\Lorry_4.jpg', 'Lorry\Lorry_5.jpg', 'Lorry\Lorry_6.jpg', 'Lorry\Lorry_7.jpg', 'Lorry\Lorry_8.jpg', 'Lorry\Lorry_9.jpg', 'Lorry\Lorry_10.jpg', 'Lorry\Lorry_11.jpg', 'Lorry\Lorry_13.jpg', 'Lorry\Lorry_14.jpg', 'Lorry\Lorry_15.jpg', 'Lorry\Lorry_16.jpg', 'Lorry\Lorry_17.jpg', 'Lorry\Lorry_19.jpg', 'Lorry\Lorry_20.jpg', 'Lorry\Lorry_22.jpg', 'Lorry\Lorry_23.jpg', 'Lorry\Lorry_24.jpg', 'Lorry\Lorry_26.jpg', 'Lorry\Lorry_27.jpg', 'Lorry\Lorry_28.jpg', 'Lorry\Lorry_30.jpg', 'Lorry\Lorry_31.jpg', 'Lorry\Lorry_32.jpg'};
            app.SelectVehicleImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVehicleImageDropDownValueChanged, true);
            app.SelectVehicleImageDropDown.Value = 'Lorry\Lorry_33.jpg';
            app.DetectionPanel = uipanel(app.UIFigure);
            app.DetectionPanel.Title = 'Detection Results';
            app.CroppedPlateAxes = uiaxes(app.DetectionPanel);
            title(app.CroppedPlateAxes, 'Detected License Plate')
            app.CroppedPlateAxes.XTick = [];
            app.CroppedPlateAxes.XTickLabel = '';
            app.CroppedPlateAxes.YTick = [];
            app.StatePanel = uipanel(app.DetectionPanel);
            app.StatePanel.Title = 'Detected State';
            app.StateImageAxes = uiaxes(app.StatePanel);
            app.StateImageAxes.XTick = [];
            app.StateImageAxes.YTick = [];
            app.StateImageAxes.ZTick = [];
            app.StateNameLabel = uilabel(app.StatePanel);
            app.StateNameLabel.Text = 'State: None';
            app.SeeDetailsButton = uibutton(app.DetectionPanel, 'push');
            app.SeeDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeDetailsButtonPushed, true);
            app.SeeDetailsButton.Text = 'See Details';
            app.PlateTypeLabel = uilabel(app.DetectionPanel);
            app.PlateTypeLabel.Text = 'Plate Type: None';
            app.LicensePlateRecognitionStateIdentificaitonSystemsLabel = uilabel(app.UIFigure);
            app.LicensePlateRecognitionStateIdentificaitonSystemsLabel.Text = 'License Plate Recognition & State Identificaiton Systems';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LPRAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end